classdef Proyecto < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ArchivoTab                      matlab.ui.container.Tab
        ReproduciroriginalButton_4      matlab.ui.control.Button
        CargarArchivoButton             matlab.ui.control.Button
        EscojaunarchivoLabel            matlab.ui.control.Label
        Canal1Tab                       matlab.ui.container.Tab
        DistorcinCheckBox               matlab.ui.control.CheckBox
        VibratoCheckBox                 matlab.ui.control.CheckBox
        EchoDelayCheckBox               matlab.ui.control.CheckBox
        ReverberacinCheckBox            matlab.ui.control.CheckBox
        PhaserCheckBox                  matlab.ui.control.CheckBox
        FrecuenciadecentroEditFieldLabel  matlab.ui.control.Label
        FrecuenciadecentroEditField     matlab.ui.control.NumericEditField
        FactordecalidadEditField_4Label  matlab.ui.control.Label
        FactordecalidadEditField_4      matlab.ui.control.NumericEditField
        FrecuenciadecortealtaEditFieldLabel  matlab.ui.control.Label
        FrecuenciadecortealtaEditField  matlab.ui.control.NumericEditField
        ReproduciroriginalButton        matlab.ui.control.Button
        ReproducirnuevoButton           matlab.ui.control.Button
        FiltroDropDownLabel             matlab.ui.control.Label
        FiltroDropDown                  matlab.ui.control.DropDown
        AplicarefectosButton            matlab.ui.control.Button
        msdeatrasoEditFieldLabel        matlab.ui.control.Label
        msdeatrasoEditField             matlab.ui.control.NumericEditField
        FreqHzSlider_3Label             matlab.ui.control.Label
        FreqHzSlider_3                  matlab.ui.control.Slider
        GananciaKnobLabel               matlab.ui.control.Label
        GananciaKnob                    matlab.ui.control.Knob
        FreqHzSliderLabel               matlab.ui.control.Label
        FreqHzSlider                    matlab.ui.control.Slider
        HzSlider                        matlab.ui.control.Slider
        FreqHzEditFieldLabel            matlab.ui.control.Label
        FreqHzEditField                 matlab.ui.control.NumericEditField
        FrecuenciadecortebajaEditFieldLabel  matlab.ui.control.Label
        FrecuenciadecortebajaEditField  matlab.ui.control.NumericEditField
        Canal2Tab                       matlab.ui.container.Tab
        DistorcinCheckBox_2             matlab.ui.control.CheckBox
        VibratoCheckBox_2               matlab.ui.control.CheckBox
        EchoDelayCheckBox_2             matlab.ui.control.CheckBox
        ReverberacinCheckBox_2          matlab.ui.control.CheckBox
        PhaserCheckBox_2                matlab.ui.control.CheckBox
        FrecuenciadecentroEditField_2Label  matlab.ui.control.Label
        FrecuenciadecentroEditField_2   matlab.ui.control.NumericEditField
        FactordecalidadEditField_5Label  matlab.ui.control.Label
        FactordecalidadEditField_5      matlab.ui.control.NumericEditField
        FrecuenciadecortealtaEditField_2Label  matlab.ui.control.Label
        FrecuenciadecortealtaEditField_2  matlab.ui.control.NumericEditField
        ReproduciroriginalButton_2      matlab.ui.control.Button
        ReproducirnuevoButton_2         matlab.ui.control.Button
        FiltroDropDown_2Label           matlab.ui.control.Label
        FiltroDropDown_2                matlab.ui.control.DropDown
        AplicarefectosButton_2          matlab.ui.control.Button
        msdeatrasoEditField_2Label      matlab.ui.control.Label
        msdeatrasoEditField_2           matlab.ui.control.NumericEditField
        GananciaKnob_2Label             matlab.ui.control.Label
        GananciaKnob_2                  matlab.ui.control.Knob
        FreqHzSlider_4Label             matlab.ui.control.Label
        FreqHzSlider_4                  matlab.ui.control.Slider
        FreqHzSlider_2Label             matlab.ui.control.Label
        FreqHzSlider_2                  matlab.ui.control.Slider
        Slider_8Label                   matlab.ui.control.Label
        Slider_8                        matlab.ui.control.Slider
        FreqHzEditField_2Label          matlab.ui.control.Label
        FreqHzEditField_2               matlab.ui.control.NumericEditField
        FrecuenciadecortebajaEditField_2Label  matlab.ui.control.Label
        FrecuenciadecortebajaEditField_2  matlab.ui.control.NumericEditField
        GrficasfiltroTab                matlab.ui.container.Tab
        ReproduciroriginalButton_3      matlab.ui.control.Button
        ReproducirnuevoButton_3         matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        GrficasdeaudioTab               matlab.ui.container.Tab
        ReproduciroriginalButton_5      matlab.ui.control.Button
        ReproducirnuevoButton_4         matlab.ui.control.Button
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
        GuardarTab                      matlab.ui.container.Tab
        ReproduciroriginalButton_6      matlab.ui.control.Button
        ReproducirnuevoButton_5         matlab.ui.control.Button
        GuardarnuevocomowavButton       matlab.ui.control.Button
        GuardarvariablescomomatButton   matlab.ui.control.Button
    end

    
    methods (Access = private)
        
        function [y2, t2]= delay_funcion(x, delay_ms, alfa, Fs)
            %Tiempo de muestreo
            ts = 1/Fs;
            
            %Calculo del numero de muestras que ocupa el delay
            delay_muestra = round((delay_ms/1000)*Fs);
            %Es importante que el tipo de dato de esta operación sea entero
            
            %tamaño de salida de la señal (numero de filas del vector columna)
            N = size(x,1);
        
            %reservar el espacio de memoria (vector columna)
            y2 = zeros(N,1);
            
            %Ecuación de Diferencias
            %Aplicamos la ecuación de diferencias
            for i = 1:N
                if i>delay_muestra
                    y2(i) = x(i) + alfa*x(i-delay_muestra);
                end
                if i<=delay_muestra
                    y2(i) = x(i);
                end
        
            end
        
            %pasamos de muestras a tiempo
            t2 = ts*(0:(N-1));
            t2 = t2.'; %Cambiamos de vector fila a vector columna
        
            %Salidas
            %y2 es la señal procesada
            %t2 es el vector de tiempo para graficar la señal (s)
            
            %Entradas
            %x es la señal de entrada
            %delay_ms es el delay en milisegundos
            %alfa es la ganacia del efecto
            %Fs es la frecuencia de muestreo
            
        end
        
        function y = reverb_funcion(x, h)
            %Se hace la convolución
            y = conv(x,h);
            %Normalizamos dividiendo entre el máximo absoluto
            m = max(y);
            g = abs(min(y)); %sacamos el valor absoluto de los extremos
            star = max(m,g); %Averiguamos cuál de los dos es mayor
            y = y/star; %Devolvemos el valor normalizado
        end
        
        function y = vibrato_funcion(x, f, ms, fs)
            %Pasamos a segundos 
            seg = ms / 1000; 
            %Delay 
            delay=round(seg*fs); % Delay base, per o en muestras
            segpulso=round(seg*fs); % modulacion del pulso, en muestras
            l=length(x);
            ancho=2+delay+segpulso*2; % Ancho del delay
            
            % Crear vectores contenedores
            ceros=zeros(ancho,1);
            y=zeros(size(x));
            
            % Creando Efecto
            for n=1:(l-1)
                m = frec/fs;
                alt = sin(m*2*pi*n);
                mod = 1+delay+segpulso*alt;
                i=floor(mod);
                ceros=[x(n);ceros(1:ancho-1)];
                y(n,1)=ceros(i+1)*(mod-i)+ceros(i)*(1-(mod-i));
            end
        end
                
        function y = distorcion_funcion(x, fc, fs)
            largo = 1:length(x);
            dist = sin(2*pi*largo*(fc/fs))';
            y = x.*dist;
        end
        
        function y = pasabandas(x, fa, fb, fs)
            N = 200;
            n = -floor(N/2):floor(N/2);
            w = hamming(N);
            f = fa/fs;
            baja = (2*f*sinc(2*f*n))' .* w;

            f = fb/fs;
            alta = (sinc(n) - 2*f*sinc(n.*2*f))' .* w;

            ybaja = filter(baja, [1], x);
            ybanda = filter(alta, [1], ybaja);
            y = ybanda;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CargarArchivoButton
        function CargarArchivoButtonPushed(app, event)
            global file;
            global fs;
            global og;
            global canal1;
            global canal2;
            [file] = uigetfile('*.wav');
            %Si no hay archivo el texto le dice al usuario que escoja uno
            if (file ==0)  
                app.EscojaunarchivoLabel.Text = ("Escoja un archivo");                
            %Si sí hay archivo, se ven los datos del archivo y el texto
            %dice el nombre del archivo que se escogio 
            else
                [og,fs]= audioread(file);
                app.EscojaunarchivoLabel.Text = file;
                %Vemos cuantos canales tienen y se guarda en una variable
                canales= size(og,2);
                %app.EscojaunarchivoLabel.Text = canales;
                if (canales==2)
                    canal1= og(:,1);
                    canal2= og(:,1);
                    
                    
                elseif (canales==1)
                    canal1=og;
                
                    
                end
                % mostrar duracion
                duration = size(og, 1)/fs;
                min = floor(duration/60);
                secs = duration - min*60;
           
                %Para graficar el audio original              
                N = fs;
                ogX1=fft(og);
                f = fs*(0:(N/2))/N;
                ogP2 = abs(ogX1)/N;
                ogP1 = ogP2(1:N/2+1);
                ogP1(2:end-1) = 2*ogP1(2:end-1);
                        
                plot(app.UIAxes3, f, ogP1);

                                       
            end
        end

        % Button pushed function: ReproduciroriginalButton, 
        % ReproduciroriginalButton_2, ReproduciroriginalButton_3, 
        % ReproduciroriginalButton_4, ReproduciroriginalButton_5, 
        % ReproduciroriginalButton_6
        function ReproduciroriginalButtonPushed(app, event)
            global file;
            global fs;
            global og;
            soundsc(og, fs);
        end

        % Value changed function: FrecuenciadecentroEditField
        function FrecuenciadecentroEditFieldValueChanged(app, event)
            global frecentro;
            frecentro = app.FrecuenciadecentroEditField.Value;
            
        end

        % Value changed function: FactordecalidadEditField_4
        function FactordecalidadEditField_4ValueChanged(app, event)
            global calidad;
            calidad = app.FactordecalidadEditField_4.Value;
            
        end

        % Value changed function: FrecuenciadecortealtaEditField
        function FrecuenciadecortealtaEditFieldValueChanged(app, event)
            global frecortea1;
            frecortea1 = app.FrecuenciadecortealtaEditField.Value;
           
        end

        % Value changed function: FrecuenciadecortebajaEditField
        function FrecuenciadecortebajaEditFieldValueChanged(app, event)
            global frecorteb1
            frecorteb1 = app.FrecuenciadecortebajaEditField.Value;
            
        end

        % Value changed function: FrecuenciadecentroEditField_2
        function FrecuenciadecentroEditField_2ValueChanged(app, event)
            global frecentro2;
            frecentro2 = app.FrecuenciadecentroEditField_2.Value;
        end

        % Value changed function: FactordecalidadEditField_5
        function FactordecalidadEditField_5ValueChanged(app, event)
            global calidad2;
            calidad2 = app.FactordecalidadEditField_5.Value;
            
        end

        % Value changed function: FrecuenciadecortealtaEditField_2
        function FrecuenciadecortealtaEditField_2ValueChanged(app, event)
            global frecortea2;
            frecortea2 = app.FrecuenciadecortealtaEditField_2.Value;
            
        end

        % Value changed function: FrecuenciadecortebajaEditField_2
        function FrecuenciadecortebajaEditField_2ValueChanged(app, event)
            global frecorteb2
            frecorteb2 = app.FrecuenciadecortebajaEditField_2.Value;
            
        end

        % Callback function
        function GananciaSliderValueChanged(app, event)
            global ganancia;
            ganancia = app.GananciaSlider.Value;
            
        end

        % Callback function
        function GananciaSlider_2ValueChanged(app, event)
            global ganancia2;
            ganancia2= app.GananciaSlider_2.Value;
            
        end

        % Value changed function: DistorcinCheckBox
        function DistorcinCheckBoxValueChanged(app, event)
            %Filtro distorción 1
            %Cada uno de estos filtros hace lo suyo, y genera un yd1, yv1 y asi
            %respectivamente. Realizar la suma ys que se generan 
            global fs;
            global og;
            global banda1;
            global yd1;
            global usdis1;
            
            value = app.DistorcinCheckBox.Value;
            if (value==1)
                yd1=app.distorcion_funcion(banda1, usdis1, fs);
            else
                yd1=zeros(size(banda1));

            end
            
            
        end

        % Value changed function: VibratoCheckBox
        function VibratoCheckBoxValueChanged(app, event)
            %Filtro vibrato 1
            global fs;
            global banda1;
            global yv1;
            global usvib1;
            
            value = app.VibratoCheckBox.Value;
            if (value==1)
                ms=500;
                yv1 = app.vibrato_funcion(banda1, usvib1, ms, fs);
            else
                yv1=zeros(size(banda1));

            end
            
        end

        % Value changed function: ReverberacinCheckBox
        function ReverberacinCheckBoxValueChanged(app, event)
            %Filtro reverberacion 1
            global fs;
            global banda1;
            global yr1;
            global usrev1;
            value = app.ReverberacinCheckBox.Value;
            if (value==1)
                 yr1 = app.reverb_funcion(banda1, usrev1);
            else
                 yr1=zeros(size(banda1));

            end
            
        end

        % Value changed function: PhaserCheckBox
        function PhaserCheckBoxValueChanged(app, event)
            %Filtro phaser 1
            global fs;
            global banda1;
            global yp1;
            if (banda1)
                value = app.PhaserCheckBox.Value;
                if (value==1)
            else
                yp1=zeros(size(banda1));

            end
        end

        % Value changed function: EchoDelayCheckBox
        function EchoDelayCheckBoxValueChanged(app, event)
            %Filtro echo 1
            global banda1;
            global ye1;
            global usdel1;
            global fs;
            if (banda1 ~=0)
                value = app.EchoDelayCheckBox.Value;                
                if (value==1)
                    alfa=100;
                    [ye1, t]= app.delay_funcion(banda1, usdel1, alfa, fs);
                else
                    ye1=zeros(size(banda1));
    
                end
            end


        end

        % Value changed function: DistorcinCheckBox_2
        function DistorcinCheckBox_2ValueChanged(app, event)
            %Filtro distorcion 2
            global banda2;
            global yd2;
            global usdis2;
            global fs;
            if (banda2~=0)
                value = app.DistorcinCheckBox.Value;
                if (value==1)
                   yd2=app.distorcion_funcion(banda2, usdis2, fs);
                else
                   yd2=zeros(size(banda2));
                end
            end
        end

        % Value changed function: VibratoCheckBox_2
        function VibratoCheckBox_2ValueChanged(app, event)
            %Filtro vibrato 2
            global fs;
            global banda2;
            global yv2;        
            global usvib2;
            if (banda2~=0)
                value = app.VibratoCheckBox_2.Value;
                if (value==1)
                    ms=500;
                    yv2 = app.vibrato_funcion(banda2, usvib2, ms, fs);
    
                else
                   yv2=zeros(size(banda2));
                end
            end

        end

        % Value changed function: ReverberacinCheckBox_2
        function ReverberacinCheckBox_2ValueChanged(app, event)
            %Filtro reverberacion 2
            global banda2;
            global yr2;
            global usrev2;
            if (banda2~=0)
               value = app.ReverberacinCheckBox_2.Value;
               if (value==1)
                   yr2 = app.reverb_funcion(banda2, usrev2);
               else
                   yr2=zeros(size(banda2));
               end 
            end
        end

        % Value changed function: PhaserCheckBox_2
        function PhaserCheckBox_2ValueChanged(app, event)
            %Filtro phaser 2
            global fs;
            global banda2;
            global yp2;
            if (banda2~=0)
               value = app.PhaserCheckBox_2.Value;
               if (value==1)

               else
                   yp2=zeros(size(banda2));
               end 
               
            end
            
        end

        % Value changed function: EchoDelayCheckBox_2
        function EchoDelayCheckBox_2ValueChanged(app, event)
            %Filtro echo 2
            global fs;
            global banda2;
            global ye2;
            global usdel2;
     
            if (banda2~=0)
               value = app.EchoDelayCheckBox_2.Value;
               if (value==1)
                    alfa=100;
                    [ye2, t]= app.delay_funcion(banda2, usdel2, alfa, fs);
                   
               else
                   ye2=zeros(size(banda2));

               end
               
            end
        end

        % Button pushed function: AplicarefectosButton
        function AplicarefectosButtonPushed(app, event)
            %Probar efectos 1
            %Sumar los ys de los efectos seleccionados y reproducir audio
            %del canal 1 modificado
            global fs;
            global og;
            global banda1;
            global yd1;
            global yr1;
            global yv1;
            global ye1;
            global yp1;
            global y1;
            
            y1=yd1+yr1+yv1+ye1+yp1;
            
        end

        % Button pushed function: AplicarefectosButton_2
        function AplicarefectosButton_2Pushed(app, event)
            %Probar efectos 2
            %Sumar los ys de los efectos seleccionados y reproducir audio
            %del canal 1 modificado
            global fs;
            global og;
            global banda2;
            global yd2;
            global yr2;
            global yv2;
            global ye2;
            global yp2;
            global y2;
            if (banda2~=0)
                y2=yd2+yr2+yv2+ye2+yp2;
                
            end
        end

        % Value changed function: FiltroDropDown
        function FiltroDropDownValueChanged(app, event)
            global filtro1;
            global canal1;
            global og;
            global fs;
            global banda1;
            filtro1 = app.FiltroDropDown.Value;  
            if (filtro1=='FIR')
                
            end
            if (filtro1== 'IIR')
                
                
            end
        end

        % Value changed function: FiltroDropDown_2
        function FiltroDropDown_2ValueChanged(app, event)
            global filtro2;
            global canal2;
            global og;
            global fs;
            global banda2;
            filtro2 = app.FiltroDropDown_2.Value;  
            if (filtro2=='FIR')
                
            end
            if (filtro2== 'IIR')
                
                
            end
        end

        % Value changed function: FreqHzEditField
        function FreqHzEditFieldValueChanged(app, event)
            global usdis1;
            usdis1 = app.FreqHzEditField.Value;
            
        end

        % Value changed function: FreqHzSlider
        function FreqHzSliderValueChanged(app, event)
            global usrev1;
            usrev1 = app.FreqHzSlider.Value;
            
        end

        % Value changed function: FreqHzEditField_2
        function FreqHzEditField_2ValueChanged(app, event)
            global usdis2;
            usdis2 = app.FreqHzEditField_2.Value;
            
        end

        % Value changed function: FreqHzSlider_2
        function FreqHzSlider_2ValueChanged(app, event)
            global usrev2;
            usrev2 = app.FreqHzSlider_2.Value;
            
        end

        % Value changed function: msdeatrasoEditField
        function msdeatrasoEditFieldValueChanged(app, event)
            global usdel1;
            usdel1 = app.msdeatrasoEditField.Value;
            
        end

        % Value changed function: msdeatrasoEditField_2
        function msdeatrasoEditField_2ValueChanged(app, event)
            global usdel2;
            usdel2 = app.msdeatrasoEditField_2.Value;
            
        end

        % Value changed function: FreqHzSlider_3
        function FreqHzSlider_3ValueChanged(app, event)
            global usvib1;
            usvib1 = app.FreqHzSlider_3.Value;
            
        end

        % Value changed function: FreqHzSlider_4
        function FreqHzSlider_4ValueChanged(app, event)
            global usvib2;
            usvib2 = app.FreqHzSlider_4.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ArchivoTab = uitab(app.TabGroup);
            app.ArchivoTab.Title = 'Archivo';
            app.ReproduciroriginalButton_4 = uibutton(app.ArchivoTab, 'push');
            app.ReproduciroriginalButton_4.ButtonPushedFcn = createCallbackFcn(app, @ReproduciroriginalButtonPushed, true);
            app.ReproduciroriginalButton_4.Text = 'Reproducir original';
            app.CargarArchivoButton = uibutton(app.ArchivoTab, 'push');
            app.CargarArchivoButton.ButtonPushedFcn = createCallbackFcn(app, @CargarArchivoButtonPushed, true);
            app.CargarArchivoButton.Text = 'Cargar Archivo';
            app.EscojaunarchivoLabel = uilabel(app.ArchivoTab);
            app.EscojaunarchivoLabel.Text = {'Escoja un archivo'; ''};
            app.Canal1Tab = uitab(app.TabGroup);
            app.Canal1Tab.Title = 'Canal 1';
            app.DistorcinCheckBox = uicheckbox(app.Canal1Tab);
            app.DistorcinCheckBox.ValueChangedFcn = createCallbackFcn(app, @DistorcinCheckBoxValueChanged, true);
            app.DistorcinCheckBox.Text = 'Distorción';
            app.VibratoCheckBox = uicheckbox(app.Canal1Tab);
            app.VibratoCheckBox.ValueChangedFcn = createCallbackFcn(app, @VibratoCheckBoxValueChanged, true);
            app.VibratoCheckBox.Text = 'Vibrato';
            app.EchoDelayCheckBox = uicheckbox(app.Canal1Tab);
            app.EchoDelayCheckBox.ValueChangedFcn = createCallbackFcn(app, @EchoDelayCheckBoxValueChanged, true);
            app.EchoDelayCheckBox.Text = 'Echo (Delay)';
            app.ReverberacinCheckBox = uicheckbox(app.Canal1Tab);
            app.ReverberacinCheckBox.ValueChangedFcn = createCallbackFcn(app, @ReverberacinCheckBoxValueChanged, true);
            app.ReverberacinCheckBox.Text = 'Reverberación';
            app.PhaserCheckBox = uicheckbox(app.Canal1Tab);
            app.PhaserCheckBox.ValueChangedFcn = createCallbackFcn(app, @PhaserCheckBoxValueChanged, true);
            app.PhaserCheckBox.Text = 'Phaser ';
            app.FrecuenciadecentroEditFieldLabel = uilabel(app.Canal1Tab);
            app.FrecuenciadecentroEditFieldLabel.Text = 'Frecuencia de centro ';
            app.FrecuenciadecentroEditField = uieditfield(app.Canal1Tab, 'numeric');
            app.FrecuenciadecentroEditField.ValueChangedFcn = createCallbackFcn(app, @FrecuenciadecentroEditFieldValueChanged, true);
            app.FactordecalidadEditField_4Label = uilabel(app.Canal1Tab);
            app.FactordecalidadEditField_4Label.Text = 'Factor de calidad';
            app.FactordecalidadEditField_4 = uieditfield(app.Canal1Tab, 'numeric');
            app.FactordecalidadEditField_4.ValueChangedFcn = createCallbackFcn(app, @FactordecalidadEditField_4ValueChanged, true);
            app.FrecuenciadecortealtaEditFieldLabel = uilabel(app.Canal1Tab);
            app.FrecuenciadecortealtaEditFieldLabel.Text = 'Frecuencia de corte alta';
            app.FrecuenciadecortealtaEditField = uieditfield(app.Canal1Tab, 'numeric');
            app.FrecuenciadecortealtaEditField.ValueChangedFcn = createCallbackFcn(app, @FrecuenciadecortealtaEditFieldValueChanged, true);
            app.ReproduciroriginalButton = uibutton(app.Canal1Tab, 'push');
            app.ReproduciroriginalButton.ButtonPushedFcn = createCallbackFcn(app, @ReproduciroriginalButtonPushed, true);
            app.ReproduciroriginalButton.Text = 'Reproducir original';
            app.ReproducirnuevoButton = uibutton(app.Canal1Tab, 'push');
            app.ReproducirnuevoButton.Text = 'Reproducir nuevo';
            app.FiltroDropDownLabel = uilabel(app.Canal1Tab);
            app.FiltroDropDownLabel.Text = 'Filtro';
            app.FiltroDropDown = uidropdown(app.Canal1Tab);
            app.FiltroDropDown.Items = {'FIR', 'IIR'};
            app.FiltroDropDown.ValueChangedFcn = createCallbackFcn(app, @FiltroDropDownValueChanged, true);
            app.FiltroDropDown.Value = 'FIR';
            app.AplicarefectosButton = uibutton(app.Canal1Tab, 'push');
            app.AplicarefectosButton.ButtonPushedFcn = createCallbackFcn(app, @AplicarefectosButtonPushed, true);
            app.AplicarefectosButton.Text = 'Aplicar efectos';
            app.msdeatrasoEditFieldLabel = uilabel(app.Canal1Tab);
            app.msdeatrasoEditFieldLabel.Text = 'ms de atraso';
            app.msdeatrasoEditField = uieditfield(app.Canal1Tab, 'numeric');
            app.msdeatrasoEditField.ValueChangedFcn = createCallbackFcn(app, @msdeatrasoEditFieldValueChanged, true);
            app.FreqHzSlider_3Label = uilabel(app.Canal1Tab);
            app.FreqHzSlider_3Label.Text = 'Freq.(Hz)';
            app.FreqHzSlider_3 = uislider(app.Canal1Tab);
            app.FreqHzSlider_3.Limits = [0 10];
            app.FreqHzSlider_3.ValueChangedFcn = createCallbackFcn(app, @FreqHzSlider_3ValueChanged, true);
            app.GananciaKnobLabel = uilabel(app.Canal1Tab);
            app.GananciaKnobLabel.Text = 'Ganancia';
            app.GananciaKnob = uiknob(app.Canal1Tab, 'continuous');
            app.GananciaKnob.Limits = [0 1];
            app.FreqHzSliderLabel = uilabel(app.Canal1Tab);
            app.FreqHzSliderLabel.Text = 'Freq. (Hz)';
            app.FreqHzSlider = uislider(app.Canal1Tab);
            app.FreqHzSlider.Limits = [0 5];
            app.FreqHzSlider.ValueChangedFcn = createCallbackFcn(app, @FreqHzSliderValueChanged, true);
            app.HzSlider = uislider(app.Canal1Tab);
            app.FreqHzEditFieldLabel = uilabel(app.Canal1Tab);
            app.FreqHzEditFieldLabel.Text = 'Freq. (Hz) ';
            app.FreqHzEditField = uieditfield(app.Canal1Tab, 'numeric');
            app.FreqHzEditField.ValueChangedFcn = createCallbackFcn(app, @FreqHzEditFieldValueChanged, true);
            app.FrecuenciadecortebajaEditFieldLabel = uilabel(app.Canal1Tab);
            app.FrecuenciadecortebajaEditFieldLabel.Text = 'Frecuencia de corte baja';
            app.FrecuenciadecortebajaEditField = uieditfield(app.Canal1Tab, 'numeric');
            app.FrecuenciadecortebajaEditField.ValueChangedFcn = createCallbackFcn(app, @FrecuenciadecortebajaEditFieldValueChanged, true);
            app.Canal2Tab = uitab(app.TabGroup);
            app.Canal2Tab.Title = 'Canal 2';
            app.DistorcinCheckBox_2 = uicheckbox(app.Canal2Tab);
            app.DistorcinCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @DistorcinCheckBox_2ValueChanged, true);
            app.DistorcinCheckBox_2.Text = 'Distorción';
            app.VibratoCheckBox_2 = uicheckbox(app.Canal2Tab);
            app.VibratoCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @VibratoCheckBox_2ValueChanged, true);
            app.VibratoCheckBox_2.Text = 'Vibrato';
            app.EchoDelayCheckBox_2 = uicheckbox(app.Canal2Tab);
            app.EchoDelayCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @EchoDelayCheckBox_2ValueChanged, true);
            app.EchoDelayCheckBox_2.Text = 'Echo (Delay)';
            app.ReverberacinCheckBox_2 = uicheckbox(app.Canal2Tab);
            app.ReverberacinCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @ReverberacinCheckBox_2ValueChanged, true);
            app.ReverberacinCheckBox_2.Text = 'Reverberación';
            app.PhaserCheckBox_2 = uicheckbox(app.Canal2Tab);
            app.PhaserCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @PhaserCheckBox_2ValueChanged, true);
            app.PhaserCheckBox_2.Text = 'Phaser ';
            app.FrecuenciadecentroEditField_2Label = uilabel(app.Canal2Tab);
            app.FrecuenciadecentroEditField_2Label.Text = 'Frecuencia de centro ';
            app.FrecuenciadecentroEditField_2 = uieditfield(app.Canal2Tab, 'numeric');
            app.FrecuenciadecentroEditField_2.ValueChangedFcn = createCallbackFcn(app, @FrecuenciadecentroEditField_2ValueChanged, true);
            app.FactordecalidadEditField_5Label = uilabel(app.Canal2Tab);
            app.FactordecalidadEditField_5Label.Text = 'Factor de calidad';
            app.FactordecalidadEditField_5 = uieditfield(app.Canal2Tab, 'numeric');
            app.FactordecalidadEditField_5.ValueChangedFcn = createCallbackFcn(app, @FactordecalidadEditField_5ValueChanged, true);
            app.FrecuenciadecortealtaEditField_2Label = uilabel(app.Canal2Tab);
            app.FrecuenciadecortealtaEditField_2Label.Text = 'Frecuencia de corte alta';
            app.FrecuenciadecortealtaEditField_2 = uieditfield(app.Canal2Tab, 'numeric');
            app.FrecuenciadecortealtaEditField_2.ValueChangedFcn = createCallbackFcn(app, @FrecuenciadecortealtaEditField_2ValueChanged, true);
            app.ReproduciroriginalButton_2 = uibutton(app.Canal2Tab, 'push');
            app.ReproduciroriginalButton_2.ButtonPushedFcn = createCallbackFcn(app, @ReproduciroriginalButtonPushed, true);
            app.ReproduciroriginalButton_2.Text = 'Reproducir original';
            app.ReproducirnuevoButton_2 = uibutton(app.Canal2Tab, 'push');
            app.ReproducirnuevoButton_2.Text = 'Reproducir nuevo';
            app.FiltroDropDown_2Label = uilabel(app.Canal2Tab);
            app.FiltroDropDown_2Label.Text = 'Filtro ';
            app.FiltroDropDown_2 = uidropdown(app.Canal2Tab);
            app.FiltroDropDown_2.Items = {'FIR', 'IIR'};
            app.FiltroDropDown_2.ValueChangedFcn = createCallbackFcn(app, @FiltroDropDown_2ValueChanged, true);
            app.FiltroDropDown_2.Value = 'FIR';
            app.AplicarefectosButton_2 = uibutton(app.Canal2Tab, 'push');
            app.AplicarefectosButton_2.ButtonPushedFcn = createCallbackFcn(app, @AplicarefectosButton_2Pushed, true);
            app.AplicarefectosButton_2.Text = 'Aplicar efectos';
            app.msdeatrasoEditField_2Label = uilabel(app.Canal2Tab);
            app.msdeatrasoEditField_2Label.Text = 'ms de atraso';
            app.msdeatrasoEditField_2 = uieditfield(app.Canal2Tab, 'numeric');
            app.msdeatrasoEditField_2.ValueChangedFcn = createCallbackFcn(app, @msdeatrasoEditField_2ValueChanged, true);
            app.GananciaKnob_2Label = uilabel(app.Canal2Tab);
            app.GananciaKnob_2Label.Text = 'Ganancia';
            app.GananciaKnob_2 = uiknob(app.Canal2Tab, 'continuous');
            app.GananciaKnob_2.Limits = [0 1];
            app.FreqHzSlider_4Label = uilabel(app.Canal2Tab);
            app.FreqHzSlider_4Label.Text = 'Freq. (Hz)';
            app.FreqHzSlider_4 = uislider(app.Canal2Tab);
            app.FreqHzSlider_4.Limits = [0 10];
            app.FreqHzSlider_4.ValueChangedFcn = createCallbackFcn(app, @FreqHzSlider_4ValueChanged, true);
            app.FreqHzSlider_2Label = uilabel(app.Canal2Tab);
            app.FreqHzSlider_2Label.Text = 'Freq. (Hz)';
            app.FreqHzSlider_2 = uislider(app.Canal2Tab);
            app.FreqHzSlider_2.Limits = [0 5];
            app.FreqHzSlider_2.ValueChangedFcn = createCallbackFcn(app, @FreqHzSlider_2ValueChanged, true);
            app.Slider_8Label = uilabel(app.Canal2Tab);
            app.Slider_8Label.Text = 'Slider';
            app.Slider_8 = uislider(app.Canal2Tab);
            app.FreqHzEditField_2Label = uilabel(app.Canal2Tab);
            app.FreqHzEditField_2Label.Text = 'Freq. (Hz)';
            app.FreqHzEditField_2 = uieditfield(app.Canal2Tab, 'numeric');
            app.FreqHzEditField_2.ValueChangedFcn = createCallbackFcn(app, @FreqHzEditField_2ValueChanged, true);
            app.FrecuenciadecortebajaEditField_2Label = uilabel(app.Canal2Tab);
            app.FrecuenciadecortebajaEditField_2Label.Text = 'Frecuencia de corte baja';
            app.FrecuenciadecortebajaEditField_2 = uieditfield(app.Canal2Tab, 'numeric');
            app.FrecuenciadecortebajaEditField_2.ValueChangedFcn = createCallbackFcn(app, @FrecuenciadecortebajaEditField_2ValueChanged, true);
            app.GrficasfiltroTab = uitab(app.TabGroup);
            app.GrficasfiltroTab.Title = 'Gráficas filtro';
            app.ReproduciroriginalButton_3 = uibutton(app.GrficasfiltroTab, 'push');
            app.ReproduciroriginalButton_3.ButtonPushedFcn = createCallbackFcn(app, @ReproduciroriginalButtonPushed, true);
            app.ReproduciroriginalButton_3.Text = 'Reproducir original';
            app.ReproducirnuevoButton_3 = uibutton(app.GrficasfiltroTab, 'push');
            app.ReproducirnuevoButton_3.Text = 'Reproducir nuevo';
            app.UIAxes = uiaxes(app.GrficasfiltroTab);
            title(app.UIAxes, 'Filtro canal 1')
            xlabel(app.UIAxes, 'Frecuencia (Hz)')
            ylabel(app.UIAxes, 'Magnitud (dB)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.GrficasfiltroTab);
            title(app.UIAxes2, 'Filtro canal 2')
            xlabel(app.UIAxes2, 'Frecuencia (Hz)')
            ylabel(app.UIAxes2, 'Magnitud (dB)')
            zlabel(app.UIAxes2, 'Z')
            app.GrficasdeaudioTab = uitab(app.TabGroup);
            app.GrficasdeaudioTab.Title = 'Gráficas de audio';
            app.ReproduciroriginalButton_5 = uibutton(app.GrficasdeaudioTab, 'push');
            app.ReproduciroriginalButton_5.ButtonPushedFcn = createCallbackFcn(app, @ReproduciroriginalButtonPushed, true);
            app.ReproduciroriginalButton_5.Text = 'Reproducir original';
            app.ReproducirnuevoButton_4 = uibutton(app.GrficasdeaudioTab, 'push');
            app.ReproducirnuevoButton_4.Text = 'Reproducir nuevo';
            app.UIAxes3 = uiaxes(app.GrficasdeaudioTab);
            title(app.UIAxes3, 'Audio original')
            xlabel(app.UIAxes3, 'Frecuencia (Hz)')
            ylabel(app.UIAxes3, 'Magnitud (dB)')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.GrficasdeaudioTab);
            title(app.UIAxes4, 'Audio modificado')
            xlabel(app.UIAxes4, 'Frecuencia (Hz)')
            ylabel(app.UIAxes4, 'Magnitud (dB)')
            zlabel(app.UIAxes4, 'Z')
            app.GuardarTab = uitab(app.TabGroup);
            app.GuardarTab.Title = 'Guardar ';
            app.ReproduciroriginalButton_6 = uibutton(app.GuardarTab, 'push');
            app.ReproduciroriginalButton_6.ButtonPushedFcn = createCallbackFcn(app, @ReproduciroriginalButtonPushed, true);
            app.ReproduciroriginalButton_6.Text = 'Reproducir original';
            app.ReproducirnuevoButton_5 = uibutton(app.GuardarTab, 'push');
            app.ReproducirnuevoButton_5.Text = 'Reproducir nuevo';
            app.GuardarnuevocomowavButton = uibutton(app.GuardarTab, 'push');
            app.GuardarnuevocomowavButton.Text = 'Guardar nuevo como .wav';
            app.GuardarvariablescomomatButton = uibutton(app.GuardarTab, 'push');
            app.GuardarvariablescomomatButton.Text = 'Guardar variables como .mat';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Proyecto

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end